classdef Expediente_Clinico1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DatosdelPacientePanel          matlab.ui.container.Panel
        InicioButton                   matlab.ui.control.Button
        EdadEditField_2                matlab.ui.control.NumericEditField
        EdadEditField_2Label           matlab.ui.control.Label
        ApellidosEditField_2           matlab.ui.control.EditField
        ApellidosEditField_2Label      matlab.ui.control.Label
        NombresEditField_2             matlab.ui.control.EditField
        NombresEditField_2Label        matlab.ui.control.Label
        ConfirmacionPanel              matlab.ui.container.Panel
        Lampconfirmacion               matlab.ui.control.Lamp
        ValidardatosSwitch             matlab.ui.control.Switch
        ValidardatosLabel              matlab.ui.control.Label
        EstudiosdelaboratorioygabinetePanel  matlab.ui.container.Panel
        Dato10EditField                matlab.ui.control.NumericEditField
        Dato10EditFieldLabel           matlab.ui.control.Label
        Dato9EditField                 matlab.ui.control.NumericEditField
        Dato9EditFieldLabel            matlab.ui.control.Label
        Dato8EditField                 matlab.ui.control.NumericEditField
        Dato8EditFieldLabel            matlab.ui.control.Label
        Dato7EditField                 matlab.ui.control.NumericEditField
        Dato7EditFieldLabel            matlab.ui.control.Label
        Dato6EditField                 matlab.ui.control.NumericEditField
        Dato6EditFieldLabel            matlab.ui.control.Label
        Dato5EditField                 matlab.ui.control.NumericEditField
        Dato5EditFieldLabel            matlab.ui.control.Label
        Dato4EditField                 matlab.ui.control.NumericEditField
        Dato4EditFieldLabel            matlab.ui.control.Label
        Dato3EditField                 matlab.ui.control.NumericEditField
        Dato3EditFieldLabel            matlab.ui.control.Label
        Dato2EditField                 matlab.ui.control.NumericEditField
        Dato2EditFieldLabel            matlab.ui.control.Label
        Dato1EditField                 matlab.ui.control.NumericEditField
        Dato1EditFieldLabel            matlab.ui.control.Label
        saturacindeoxgenoenlasangreSaO2oSpO2Label  matlab.ui.control.Label
        SATURACIONDEOXIGENOPanel       matlab.ui.container.Panel
        SaturacionOxigeno              matlab.ui.control.UIAxes
        ELECTROCARDIOGRAMAPanel        matlab.ui.container.Panel
        ECG                            matlab.ui.control.UIAxes
        HistoriaclinicaPanel           matlab.ui.container.Panel
        UsodealcoholtabacoosustanciaspsicoactivasEditField  matlab.ui.control.EditField
        UsodealcoholtabacoosustanciaspsicoactivasEditFieldLabel  matlab.ui.control.Label
        TratamientospreviosEditField   matlab.ui.control.EditField
        TratamientospreviosEditFieldLabel  matlab.ui.control.Label
        PadecimientoactualEditField    matlab.ui.control.EditField
        PadecimientoactualEditFieldLabel  matlab.ui.control.Label
        AntecedentespersonalesnopatologicosEditField  matlab.ui.control.EditField
        AntecedentespersonalesnopatologicosEditFieldLabel  matlab.ui.control.Label
        AntecendetespersonalespatologicosEditField  matlab.ui.control.EditField
        AntecendetespersonalespatologicosEditFieldLabel  matlab.ui.control.Label
        AntecendentesheredofamiliaresEditField  matlab.ui.control.EditField
        AntecendentesheredofamiliaresLabel  matlab.ui.control.Label
        GrupoetnicoEditField           matlab.ui.control.EditField
        GrupoetnicoEditFieldLabel      matlab.ui.control.Label
        FolioEditField                 matlab.ui.control.NumericEditField
        FolioEditFieldLabel            matlab.ui.control.Label
        ExploracinFisicaPanel          matlab.ui.container.Panel
        TallaEditField                 matlab.ui.control.NumericEditField
        TallaEditFieldLabel            matlab.ui.control.Label
        IMCEditField                   matlab.ui.control.NumericEditField
        IMCEditFieldLabel              matlab.ui.control.Label
        PesoEditField                  matlab.ui.control.NumericEditField
        PesoEditFieldLabel             matlab.ui.control.Label
        FrecuenciarespiratoriaEditField  matlab.ui.control.NumericEditField
        FrecuenciarespiratoriaEditFieldLabel  matlab.ui.control.Label
        FreuenciacardiacaEditField     matlab.ui.control.NumericEditField
        FreuenciacardiacaEditFieldLabel  matlab.ui.control.Label
        TensinArterialEditField        matlab.ui.control.EditField
        TensinArterialEditFieldLabel   matlab.ui.control.Label
        TemperaturaCEditField          matlab.ui.control.NumericEditField
        TemperaturaCEditFieldLabel     matlab.ui.control.Label
        SignosvitalesLabel             matlab.ui.control.Label
        HabitusexteriorEditField       matlab.ui.control.EditField
        HabitusexteriorEditFieldLabel  matlab.ui.control.Label
        DatosgeneralesPanel            matlab.ui.container.Panel
        SexoMoFEditField               matlab.ui.control.EditField
        SexoMoFEditFieldLabel          matlab.ui.control.Label
        Datos                          matlab.ui.control.Label
        ContraseaEditField             matlab.ui.control.EditField
        ContraseaEditFieldLabel        matlab.ui.control.Label
        CargarDatosButton              matlab.ui.control.Button
        LampPassword                   matlab.ui.control.Lamp
        Validar                        matlab.ui.control.Label
        AccederButton                  matlab.ui.control.Button
        DomicilioPanel                 matlab.ui.container.Panel
        NumeroexteriorEditField        matlab.ui.control.EditField
        NumeroexteriorEditFieldLabel   matlab.ui.control.Label
        NumerointeriorEditField        matlab.ui.control.EditField
        NumerointeriorEditFieldLabel   matlab.ui.control.Label
        CalleEditField                 matlab.ui.control.EditField
        CalleEditFieldLabel            matlab.ui.control.Label
        ColoniaEditField               matlab.ui.control.EditField
        ColoniaEditFieldLabel          matlab.ui.control.Label
        CodigopostalEditField          matlab.ui.control.NumericEditField
        CodigopostalEditFieldLabel     matlab.ui.control.Label
        MunicipiooalcaldiaEditField    matlab.ui.control.EditField
        MunicipiooalcaldiaEditFieldLabel  matlab.ui.control.Label
        EntidadfederativaEditField     matlab.ui.control.EditField
        EntidadfederativaEditFieldLabel  matlab.ui.control.Label
        EdadEditField                  matlab.ui.control.NumericEditField
        EdadEditFieldLabel             matlab.ui.control.Label
        ApellidosEditField             matlab.ui.control.EditField
        ApellidosEditFieldLabel        matlab.ui.control.Label
        NombresEditField               matlab.ui.control.EditField
        NombresEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Folio % Folio
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CargarDatosButton.Enable = 'off';
            app.ValidardatosSwitch.Enable = 'off';
            app.IMCEditField.Enable = 'off';
            app.FolioEditField.Editable = 'off'
            app.FolioEditField.FontWeight = 'bold';
            app.Folio = 0;
            app.ECG.Visible = 'off';
            
        end

        % Button pushed function: AccederButton
        function AccederButtonPushed(app, event)
            PW = '1234';
            Password = app.ContraseaEditField.Value;
            if  strcmp(Password,PW)
                app.LampPassword.Color = 'g';
                app.Validar.Text = 'Correcto';
                app.CargarDatosButton.Enable = 'on';
                app.ContraseaEditField.Value = '****';
                app.ContraseaEditField.Enable = 'off';
                app.AccederButton.Enable = 'off';
                app.Folio = app.Folio + 1; % Contador del folio
                app.FolioEditField.Enable = 'on';
                app.FolioEditField.Visible = 'on';
                app.FolioEditFieldLabel.Visible = 'on'
                app.FolioEditField.Value = app.Folio;
            else
                app.LampPassword.Color = 'r';
                app.Validar.Text = 'Incorrecto';
            end
            
        end

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            %Verificar casillas vacias
            %Datos generales
            a = strcmp(app.NombresEditField.Value,'');
            b = strcmp(app.ApellidosEditField.Value,'');
            c = strcmp(num2str(app.EdadEditField.Value),'0'); %Comparacion 0 en ascii
            %Direccion
            d = strcmp(app.NumeroexteriorEditField.Value,'');
            e = strcmp(app.NumerointeriorEditField.Value,'');
            f = strcmp(app.CalleEditField.Value,'');
            g = strcmp(app.ColoniaEditField.Value,'');
            h = strcmp(num2str(app.CodigopostalEditField.Value),'0');
            i = strcmp(app.MunicipiooalcaldiaEditField.Value,'');
            j = strcmp(app.EntidadfederativaEditField.Value,'');
            %Historia Clínica
            k = strcmp(app.UsodealcoholtabacoosustanciaspsicoactivasEditField.Value,'');
            l = strcmp(app.TratamientospreviosEditField.Value,'');
            m = strcmp(app.PadecimientoactualEditField.Value,'');
            n = strcmp(app.AntecedentespersonalesnopatologicosEditField.Value,'');
            o = strcmp(app.AntecendetespersonalespatologicosEditField.Value,'');
            p = strcmp(app.AntecendentesheredofamiliaresEditField.Value,'');
            q = strcmp(app.GrupoetnicoEditField.Value,'');
            %Exploracion Fisica
            r = strcmp(app.HabitusexteriorEditField.Value,'');
            s = strcmp(num2str(app.TallaEditField.Value),'0');
            t = strcmp(app.TensinArterialEditField.Value,'');
            w = strcmp(num2str(app.PesoEditField.Value),'0');
            x = strcmp(num2str(app.FrecuenciarespiratoriaEditField.Value),'0');
            y = strcmp(num2str(app.FreuenciacardiacaEditField.Value),'0');
            z = strcmp(num2str(app.TemperaturaCEditField.Value),'0');

            %%FALTA AGREGAR DATOS PARA GRAFICA DE SATURACION DE OXIGENO
            s1 = strcmp(num2str(app.Dato1EditField.Value),'0');  
            s2 = strcmp(num2str(app.Dato2EditField.Value),'0'); 
            s3 = strcmp(num2str(app.Dato3EditField.Value),'0'); 
            s4 = strcmp(num2str(app.Dato4EditField.Value),'0'); 
            s5 = strcmp(num2str(app.Dato5EditField.Value),'0'); 
            s6 = strcmp(num2str(app.Dato6EditField.Value),'0'); 
            s7 = strcmp(num2str(app.Dato7EditField.Value),'0'); 
            s8 = strcmp(num2str(app.Dato8EditField.Value),'0'); 
            s9 = strcmp(num2str(app.Dato9EditField.Value),'0'); 
            s10 = strcmp(num2str(app.Dato10EditField.Value),'0'); 


            if  (a == 1 || b == 1 || c == 1 || d == 1 || e == 1 || f == 1 || g == 1 || h == 1 || i == 1 || j == 1 || k == 1  || l == 1  || m == 1 || n == 1 || o == 1 || p == 1 || q == 1 || r == 1 || s == 1 || t == 1 || w == 1 || x == 1 || y == 1 || z == 1 || s1 == 1 || s2 == 1 || s3 == 1 || s4 == 1 || s5 == 1 || s6 == 1 || s7 == 1 || s8 == 1 || s9 == 1 || s10 == 1) %Datos vacios
                 app.Datos.Text = 'Porfavor completa todos los campos';
            else %Datos completos
                app.Datos.Text = 'Datos completos';
                app.ValidardatosSwitch.Enable = 'on';

                %%ECG 
                app.ECG.Visible = 'on';

                ECG = [109,109,109,109,109,109,110,110,110,110,111,112,114,116,120,123,128,132,136,140,145,149,153,158,162,165,167,168,167,164];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
                fs = 200;
                ts = 1/fs;
                tf = 200*ts % tiempo final = Datos totales * ts
                t = 0:ts:tf-ts;
            
                plot(app.ECG,t,ECG)
                
                %%Saturacion de oxigeno
                sat1 = app.Dato1EditField.Value;
                sat2 = app.Dato2EditField.Value;
                sat3 = app.Dato3EditField.Value;
                sat4 = app.Dato4EditField.Value;
                sat5 = app.Dato5EditField.Value;
                sat6 = app.Dato6EditField.Value;
                sat7 = app.Dato7EditField.Value;
                sat8 = app.Dato8EditField.Value;
                sat9 = app.Dato9EditField.Value;
                sat10 = app.Dato10EditField.Value;

                Vsat = [sat1, sat2, sat3, sat4, sat5, sat6, sat7, sat8, sat9,sat10];
                    
                Presion = 10:10:100;

                plot(app.SaturacionOxigeno,Presion,Vsat)
                

            end
        end

        % Value changed function: ValidardatosSwitch
        function ValidardatosSwitchValueChanged(app, event)
            confirmacion = app.ValidardatosSwitch.Value;
            if strcmp(confirmacion,'On')
                app.CargarDatosButton.Enable = 'off';
                app.ValidardatosSwitch.Enable = 'off';
                app.Lampconfirmacion.Color = 'g';
                
                %Guardar datos finales
                nombre = app.NombresEditField.Value;
                apellido = app.ApellidosEditField.Value;

                %Se confirmo bloqueamos campos
                app.NombresEditField.Enable = 'off';
                app.ApellidosEditField.Enable = 'off';
                app.EdadEditField.Enable = 'off';
                app.UsodealcoholtabacoosustanciaspsicoactivasEditField.Enable = 'off';
                app.TratamientospreviosEditField.Enable = 'off';
                app.PadecimientoactualEditField.Enable = 'off';
                app.AntecedentespersonalesnopatologicosEditField.Enable = 'off';
                app.AntecendetespersonalespatologicosEditField.Enable = 'off';
                app.AntecendentesheredofamiliaresEditField.Enable = 'off';
                app.GrupoetnicoEditField.Enable = 'off';
                app.IMCEditField.Enable = 'off';
                app.TallaEditField.Enable = 'off';
                app.PesoEditField.Enable = 'off';
                app.FrecuenciarespiratoriaEditField.Enable = 'off';
                app.FreuenciacardiacaEditField.Enable = 'off';
                app.TensinArterialEditField.Enable = 'off';
                app.TemperaturaCEditField.Enable = 'off';
                app.HabitusexteriorEditField.Enable = 'off';
                app.NumeroexteriorEditField.Enable = 'off';
                app.NumerointeriorEditField.Enable = 'off';
                app.CalleEditField.Enable = 'off';
                app.ColoniaEditField.Enable = 'off';
                app.CodigopostalEditField.Enable = 'off';
                app.MunicipiooalcaldiaEditField.Enable = 'off';
                app.EntidadfederativaEditField.Enable = 'off';
                
               
                % Mostrar datos finales supongo que solo algunos
                app.NombresEditField_2.Value = nombre;
                app.NombresEditField_2.Editable = 'off';
                app.NombresEditField_2.FontWeight = 'bold';
                app.ApellidosEditField_2.Value = apellido;
                app.ApellidosEditField_2.Editable = 'off';
                app.ApellidosEditField_2.FontWeight = 'bold';
                %FALTANNNN

                %Regresar al inicio
                app.InicioButton.Visible = 'on';

            end
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            app.FolioEditField.Visible = 'off';
            app.FolioEditFieldLabel.Visible = 'off'
            app.LampPassword.Color = 'r';

            %%Borrar todos los campos  y regresarlos a activados
            app.NombresEditField.Enable = 'on';
            app.NombresEditField.Value = '';
            app.ApellidosEditField.Enable = 'on';
            app.ApellidosEditField.Value = '';
            app.EdadEditField.Enable = 'on';
            app.EdadEditField.Value = 0;
            app.UsodealcoholtabacoosustanciaspsicoactivasEditField.Enable = 'on';
            app.UsodealcoholtabacoosustanciaspsicoactivasEditField.Value = '';
            app.TratamientospreviosEditField.Enable = 'on';
            app.TratamientospreviosEditField.Value = '';
            app.PadecimientoactualEditField.Enable = 'on';
            app.PadecimientoactualEditField.Value = '';
            app.AntecedentespersonalesnopatologicosEditField.Enable = 'on';
            app.AntecedentespersonalesnopatologicosEditField.Value = '';
            app.AntecendetespersonalespatologicosEditField.Enable = 'on';
            app.AntecendetespersonalespatologicosEditField.Value = '';
            app.AntecendentesheredofamiliaresEditField.Enable = 'on';
            app.AntecendentesheredofamiliaresEditField.Value = '';
            app.GrupoetnicoEditField.Enable = 'on';
            app.GrupoetnicoEditField.Value = '';
            app.IMCEditField.Enable = 'on';
            app.IMCEditField.Value = 0;
            app.TallaEditField.Enable = 'on';
            app.TallaEditField.Value = 0;
            app.PesoEditField.Enable = 'on';
            app.PesoEditField.Value = 0;
            app.FrecuenciarespiratoriaEditField.Enable = 'on';
            app.FrecuenciarespiratoriaEditField.Value = 0;
            app.FreuenciacardiacaEditField.Enable = 'on';
            app.FreuenciacardiacaEditField.Value = 0;
            app.TensinArterialEditField.Enable = 'on';
            app.TensinArterialEditField.Value = '';
            app.TemperaturaCEditField.Enable = 'on';
            app.TemperaturaCEditField.Value = 0;
            app.HabitusexteriorEditField.Enable = 'on';
            app.HabitusexteriorEditField.Value = '';
            app.NumeroexteriorEditField.Enable = 'on';
            app.NumeroexteriorEditField.Value = '';
            app.NumerointeriorEditField.Enable = 'on';
            app.NumerointeriorEditField.Value = '';
            app.CalleEditField.Enable = 'on';
            app.CalleEditField.Value = '';
            app.ColoniaEditField.Enable = 'on';
            app.ColoniaEditField.Value = '';
            app.CodigopostalEditField.Enable = 'on';
            app.CodigopostalEditField.Value = 0;
            app.MunicipiooalcaldiaEditField.Enable = 'on';
            app.MunicipiooalcaldiaEditField.Value = '';
            app.EntidadfederativaEditField.Enable = 'on';
            app.EntidadfederativaEditField.Value = '';

            %%Activar acceder con password
            app.AccederButton.Enable = 'on';
            app.ContraseaEditField.Enable = 'on';
            app.ContraseaEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosgeneralesPanel = uipanel(app.UIFigure);
            app.DatosgeneralesPanel.Title = 'Datos generales';
            app.NombresEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.NombresEditFieldLabel.Text = 'Nombre(s)';
            app.NombresEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.ApellidosEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.ApellidosEditFieldLabel.Text = 'Apellido(s)';
            app.ApellidosEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.EdadEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.DatosgeneralesPanel, 'numeric');
            app.DomicilioPanel = uipanel(app.DatosgeneralesPanel);
            app.DomicilioPanel.Title = 'Domicilio';
            app.EntidadfederativaEditFieldLabel = uilabel(app.DomicilioPanel);
            app.EntidadfederativaEditFieldLabel.Text = 'Entidad federativa';
            app.EntidadfederativaEditField = uieditfield(app.DomicilioPanel, 'text');
            app.MunicipiooalcaldiaEditFieldLabel = uilabel(app.DomicilioPanel);
            app.MunicipiooalcaldiaEditFieldLabel.Text = 'Municipio o alcaldia';
            app.MunicipiooalcaldiaEditField = uieditfield(app.DomicilioPanel, 'text');
            app.CodigopostalEditFieldLabel = uilabel(app.DomicilioPanel);
            app.CodigopostalEditFieldLabel.Text = 'Codigo postal';
            app.CodigopostalEditField = uieditfield(app.DomicilioPanel, 'numeric');
            app.ColoniaEditFieldLabel = uilabel(app.DomicilioPanel);
            app.ColoniaEditFieldLabel.Text = 'Colonia';
            app.ColoniaEditField = uieditfield(app.DomicilioPanel, 'text');
            app.CalleEditFieldLabel = uilabel(app.DomicilioPanel);
            app.CalleEditFieldLabel.Text = 'Calle';
            app.CalleEditField = uieditfield(app.DomicilioPanel, 'text');
            app.NumerointeriorEditFieldLabel = uilabel(app.DomicilioPanel);
            app.NumerointeriorEditFieldLabel.Text = 'Numero interior';
            app.NumerointeriorEditField = uieditfield(app.DomicilioPanel, 'text');
            app.NumeroexteriorEditFieldLabel = uilabel(app.DomicilioPanel);
            app.NumeroexteriorEditFieldLabel.Text = 'Numero exterior';
            app.NumeroexteriorEditField = uieditfield(app.DomicilioPanel, 'text');
            app.AccederButton = uibutton(app.DatosgeneralesPanel, 'push');
            app.AccederButton.ButtonPushedFcn = createCallbackFcn(app, @AccederButtonPushed, true);
            app.AccederButton.Text = 'Acceder';
            app.Validar = uilabel(app.DatosgeneralesPanel);
            app.Validar.Text = '';
            app.LampPassword = uilamp(app.DatosgeneralesPanel);
            app.CargarDatosButton = uibutton(app.DatosgeneralesPanel, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.ContraseaEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.Datos = uilabel(app.DatosgeneralesPanel);
            app.Datos.Text = '';
            app.SexoMoFEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.SexoMoFEditFieldLabel.Text = 'Sexo (M o F)';
            app.SexoMoFEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.ExploracinFisicaPanel = uipanel(app.UIFigure);
            app.ExploracinFisicaPanel.Title = 'Exploración Fisica';
            app.HabitusexteriorEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.HabitusexteriorEditFieldLabel.Text = 'Habitus exterior';
            app.HabitusexteriorEditField = uieditfield(app.ExploracinFisicaPanel, 'text');
            app.SignosvitalesLabel = uilabel(app.ExploracinFisicaPanel);
            app.SignosvitalesLabel.Text = 'Signos vitales';
            app.TemperaturaCEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.TemperaturaCEditFieldLabel.Text = 'Temperatura °C';
            app.TemperaturaCEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.TensinArterialEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.TensinArterialEditFieldLabel.Text = 'Tensión Arterial';
            app.TensinArterialEditField = uieditfield(app.ExploracinFisicaPanel, 'text');
            app.FreuenciacardiacaEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.FreuenciacardiacaEditFieldLabel.Text = 'Freuencia cardiaca';
            app.FreuenciacardiacaEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.FrecuenciarespiratoriaEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.FrecuenciarespiratoriaEditFieldLabel.Text = 'Frecuencia respiratoria';
            app.FrecuenciarespiratoriaEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.PesoEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.PesoEditFieldLabel.Text = 'Peso';
            app.PesoEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.IMCEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.IMCEditFieldLabel.Text = 'IMC';
            app.IMCEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.TallaEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.TallaEditFieldLabel.Text = 'Talla';
            app.TallaEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.HistoriaclinicaPanel = uipanel(app.UIFigure);
            app.HistoriaclinicaPanel.Title = 'Historia clinica';
            app.FolioEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.FolioEditFieldLabel.Visible = 'off';
            app.FolioEditFieldLabel.Text = 'Folio';
            app.FolioEditField = uieditfield(app.HistoriaclinicaPanel, 'numeric');
            app.FolioEditField.Visible = 'off';
            app.GrupoetnicoEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.GrupoetnicoEditFieldLabel.Text = 'Grupo etnico';
            app.GrupoetnicoEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.AntecendentesheredofamiliaresLabel = uilabel(app.HistoriaclinicaPanel);
            app.AntecendentesheredofamiliaresLabel.Text = {'Antecendentes'; 'heredofamiliares'};
            app.AntecendentesheredofamiliaresEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.AntecendetespersonalespatologicosEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.AntecendetespersonalespatologicosEditFieldLabel.Text = {'Antecendetes'; 'personales'; 'patologicos'};
            app.AntecendetespersonalespatologicosEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.AntecedentespersonalesnopatologicosEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.AntecedentespersonalesnopatologicosEditFieldLabel.Text = {'Antecedentes'; 'personales'; 'no patologicos'};
            app.AntecedentespersonalesnopatologicosEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.PadecimientoactualEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.PadecimientoactualEditFieldLabel.Text = {'Padecimiento'; 'actual'};
            app.PadecimientoactualEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.TratamientospreviosEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.TratamientospreviosEditFieldLabel.Text = {'Tratamientos '; 'previos'};
            app.TratamientospreviosEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.UsodealcoholtabacoosustanciaspsicoactivasEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.UsodealcoholtabacoosustanciaspsicoactivasEditFieldLabel.Text = {'Uso de alcohol,'; 'tabaco o sustancias'; 'psicoactivas'};
            app.UsodealcoholtabacoosustanciaspsicoactivasEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.EstudiosdelaboratorioygabinetePanel = uipanel(app.UIFigure);
            app.EstudiosdelaboratorioygabinetePanel.Title = 'Estudios de laboratorio y gabinete';
            app.ELECTROCARDIOGRAMAPanel = uipanel(app.EstudiosdelaboratorioygabinetePanel);
            app.ELECTROCARDIOGRAMAPanel.Title = 'ELECTROCARDIOGRAMA';
            app.ECG = uiaxes(app.ELECTROCARDIOGRAMAPanel);
            title(app.ECG, 'Title')
            xlabel(app.ECG, 'X')
            ylabel(app.ECG, 'Y')
            zlabel(app.ECG, 'Z')
            app.SATURACIONDEOXIGENOPanel = uipanel(app.EstudiosdelaboratorioygabinetePanel);
            app.SATURACIONDEOXIGENOPanel.Title = 'SATURACION DE OXIGENO';
            app.SaturacionOxigeno = uiaxes(app.SATURACIONDEOXIGENOPanel);
            title(app.SaturacionOxigeno, 'Title')
            xlabel(app.SaturacionOxigeno, 'X')
            ylabel(app.SaturacionOxigeno, 'Y')
            zlabel(app.SaturacionOxigeno, 'Z')
            app.saturacindeoxgenoenlasangreSaO2oSpO2Label = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.saturacindeoxgenoenlasangreSaO2oSpO2Label.Text = 'saturación de oxígeno en la sangre (SaO2 o SpO2)';
            app.Dato1EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato1EditFieldLabel.Text = 'Dato1';
            app.Dato1EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato2EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato2EditFieldLabel.Text = 'Dato2';
            app.Dato2EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato3EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato3EditFieldLabel.Text = 'Dato3';
            app.Dato3EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato4EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato4EditFieldLabel.Text = 'Dato4';
            app.Dato4EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato5EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato5EditFieldLabel.Text = 'Dato5';
            app.Dato5EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato6EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato6EditFieldLabel.Text = 'Dato6';
            app.Dato6EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato7EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato7EditFieldLabel.Text = 'Dato7';
            app.Dato7EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato8EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato8EditFieldLabel.Text = 'Dato8';
            app.Dato8EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato9EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato9EditFieldLabel.Text = 'Dato9';
            app.Dato9EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato10EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato10EditFieldLabel.Text = 'Dato10';
            app.Dato10EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.ConfirmacionPanel = uipanel(app.UIFigure);
            app.ConfirmacionPanel.Title = 'Confirmacion';
            app.ValidardatosLabel = uilabel(app.ConfirmacionPanel);
            app.ValidardatosLabel.Text = {'Validar datos'; ''};
            app.ValidardatosSwitch = uiswitch(app.ConfirmacionPanel, 'slider');
            app.ValidardatosSwitch.ValueChangedFcn = createCallbackFcn(app, @ValidardatosSwitchValueChanged, true);
            app.Lampconfirmacion = uilamp(app.ConfirmacionPanel);
            app.DatosdelPacientePanel = uipanel(app.UIFigure);
            app.DatosdelPacientePanel.Title = 'Datos del Paciente';
            app.NombresEditField_2Label = uilabel(app.DatosdelPacientePanel);
            app.NombresEditField_2Label.Text = 'Nombre(s)';
            app.NombresEditField_2 = uieditfield(app.DatosdelPacientePanel, 'text');
            app.ApellidosEditField_2Label = uilabel(app.DatosdelPacientePanel);
            app.ApellidosEditField_2Label.Text = 'Apellido(s)';
            app.ApellidosEditField_2 = uieditfield(app.DatosdelPacientePanel, 'text');
            app.EdadEditField_2Label = uilabel(app.DatosdelPacientePanel);
            app.EdadEditField_2Label.Text = 'Edad';
            app.EdadEditField_2 = uieditfield(app.DatosdelPacientePanel, 'numeric');
            app.InicioButton = uibutton(app.DatosdelPacientePanel, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Visible = 'off';
            app.InicioButton.Text = 'Inicio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Expediente_Clinico1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end